classdef AutomaticHuApplication < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        HUApplicationPanel  matlab.ui.container.Panel
        BrowseButton        matlab.ui.control.Button
        DirectoryPathLabel  matlab.ui.control.Label
        DirectoryContents   matlab.ui.control.Table
        AlgorithmHUButton   matlab.ui.control.Button
        TabellaRisultati    matlab.ui.control.Table
    end

    
    methods (Access = private)
        
        % -------------------------- funzione Hu_Moments --------------------------
        function phi = Hu_Moments(~, F)
            %INVMOMENTS Compute invariant moments of image.
            %   PHI = INVMOMENTS(F) computes the moment invariants of the image
            %   F. PHI is a seven-element row vector containing the moment
            %   invariants as defined in equations (11.3-17) through (11.3-23) of
            %   Gonzalez and Woods, Digital Image Processing, 2nd Ed.
            %
            %   F must be a 2-D, real, nonsparse, numeric or logical matrix.
            
            %   Copyright 2002-2004 R. C. Gonzalez, R. E. Woods, & S. L. Eddins
            %   Digital Image Processing Using MATLAB, Prentice-Hall, 2004
            %   $Revision: 1.5 $  $Date: 2003/11/21 14:39:19 $
            
            if (ndims(F) ~= 2) || issparse(F) || ~isreal(F) || ~(isnumeric(F) || islogical(F))
               error(['F must be a 2-D, real, nonsparse, numeric or logical ' 'matrix.']);                       
            end
            
            F = double(F);
            phi = compute_phi(compute_eta(compute_m(F)));            
                
           
            % -------------------------- funzione compute_m --------------------------
            function m = compute_m(F)
                [M, N] = size(F);
                [x, y] = meshgrid(1:N, 1:M);
                  
                % Turn x, y, and F into column vectors to make the summations a bit
                % easier to compute in the following.
                x = x(:);
                y = y(:);
                F = F(:);
                  
                % DIP equation (11.3-12)
                m.m00 = sum(F);
                % Protect against divide-by-zero warnings.
                if (m.m00 == 0)
                   m.m00 = eps;
                end
                % The other central moments:  
                m.m10 = sum(x .* F);
                m.m01 = sum(y .* F);
                m.m11 = sum(x .* y .* F);
                m.m20 = sum(x.^2 .* F);
                m.m02 = sum(y.^2 .* F);
                m.m30 = sum(x.^3 .* F);
                m.m03 = sum(y.^3 .* F);
                m.m12 = sum(x .* y.^2 .* F);
                m.m21 = sum(x.^2 .* y .* F);            
            end
            
            % -------------------------- funzione compute_eta --------------------------
            function e = compute_eta(m)
    
                % DIP equations (11.3-14) through (11.3-16).
                
                xbar = m.m10 / m.m00;
                ybar = m.m01 / m.m00;
                
                e.eta11 = (m.m11 - ybar*m.m10) / m.m00^2;
                e.eta20 = (m.m20 - xbar*m.m10) / m.m00^2;
                e.eta02 = (m.m02 - ybar*m.m01) / m.m00^2;
                e.eta30 = (m.m30 - 3 * xbar * m.m20 + 2 * xbar^2 * m.m10) / m.m00^2.5;
                e.eta03 = (m.m03 - 3 * ybar * m.m02 + 2 * ybar^2 * m.m01) / m.m00^2.5;
                e.eta21 = (m.m21 - 2 * xbar * m.m11 - ybar * m.m20 + 2 * xbar^2 * m.m01) / m.m00^2.5;                       
                e.eta12 = (m.m12 - 2 * ybar * m.m11 - xbar * m.m02 + 2 * ybar^2 * m.m10) / m.m00^2.5;                               
            end
            
            % -------------------------- funzione compute_phi --------------------------
            function phi = compute_phi(e)
                % DIP equations (11.3-17) through (11.3-23).
                
                phi(1) = e.eta20 + e.eta02;
                phi(2) = (e.eta20 - e.eta02)^2 + 4*e.eta11^2;
                phi(3) = (e.eta30 - 3*e.eta12)^2 + (3*e.eta21 - e.eta03)^2;
                phi(4) = (e.eta30 + e.eta12)^2 + (e.eta21 + e.eta03)^2;
                phi(5) = (e.eta30 - 3*e.eta12) * (e.eta30 + e.eta12) * ...
                         ( (e.eta30 + e.eta12)^2 - 3*(e.eta21 + e.eta03)^2 ) + ...
                         (3*e.eta21 - e.eta03) * (e.eta21 + e.eta03) * ...
                         ( 3*(e.eta30 + e.eta12)^2 - (e.eta21 + e.eta03)^2 );
                phi(6) = (e.eta20 - e.eta02) * ( (e.eta30 + e.eta12)^2 - ...
                                                 (e.eta21 + e.eta03)^2 ) + ...
                         4 * e.eta11 * (e.eta30 + e.eta12) * (e.eta21 + e.eta03);
                phi(7) = (3*e.eta21 - e.eta03) * (e.eta30 + e.eta12) * ...
                         ( (e.eta30 + e.eta12)^2 - 3*(e.eta21 + e.eta03)^2 ) + ...
                         (3*e.eta12 - e.eta30) * (e.eta21 + e.eta03) * ...
                         ( 3*(e.eta30 + e.eta12)^2 - (e.eta21 + e.eta03)^2 );           
            end
        end    
        
        % ---------------- Funzione per trasformare la figura in 2D ----------------
        %function grayscale(app)
        %    global image1
        %    image1 = rgb2gray(image1);
        %end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            % variabili globali 
            global a
            global path
            
            path = uigetdir;                       % 'uigetdir' recupera il path in base alla cartella selezionata dall'utente
            
            app.UIFigure.Visible = 'off';          % queste due righe gestiscono i casi in cui l'app rimane sopra tutte le finestre
            app.UIFigure.Visible = 'on';           % e i casi in cui rimane in background
            
            app.DirectoryPathLabel.Text = path;    % il path della cartella salvato in precedenza viene mostrato nella label 

            a = dir(path);                           % recupero il contenuto della cartella attraverso il path e la funzione 'dir'
            imageVector = {a(:).name}';                        % recupero tutti i nomi dei vari file presenti nella cartella e li salvo in un array
            imageVector(ismember(imageVector,{'.','..'})) = [];          % rimuovo eventuali 'punti' o 'doppi punti' che non sono necessari

            app.DirectoryContents.Data = imageVector;                    % mostro il contenuto della cartella nella tabella "Directory Contents"
        end

        % Button pushed function: AlgorithmHUButton
        function AlgorithmHUButtonPushed(app, event)
            % variabili globali
            global image1
            global a
            global path
                       
            % ciclo per scorrere il vettore in cui sono contenute le
            % immagini, applicare la funzione e salvare ogni valore in una
            % riga della seconda tabella
            
            for k = 1:length(a)
                baseFileName = a(k).name;
                fullFileName = fullfile(path, baseFileName);
                image1 = imread(fullFileName);
                grayscale(app);
                
                Risultati = app.Hu_Moments(image1);
            
                app.TabellaRisultati.Data(k, 1:7) = Risultati;
                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.HUApplicationPanel = uipanel(app.UIFigure);
            app.HUApplicationPanel.Title = 'HU Application';
            app.BrowseButton = uibutton(app.HUApplicationPanel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Icon = 'Icon_Folder.png';
            app.BrowseButton.Text = {'     Browse'; ''};
            app.DirectoryPathLabel = uilabel(app.HUApplicationPanel);
            app.DirectoryPathLabel.Text = '   Directory Path';
            app.DirectoryContents = uitable(app.HUApplicationPanel);
            app.DirectoryContents.ColumnName = {'Directory Content'};
            app.DirectoryContents.RowName = {};
            app.AlgorithmHUButton = uibutton(app.HUApplicationPanel, 'push');
            app.AlgorithmHUButton.ButtonPushedFcn = createCallbackFcn(app, @AlgorithmHUButtonPushed, true);
            app.AlgorithmHUButton.Text = {'Algorithm HU'; ''};
            app.TabellaRisultati = uitable(app.HUApplicationPanel);
            app.TabellaRisultati.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'; 'Column 5'; 'Column 6'; 'Column 7'};
            app.TabellaRisultati.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AutomaticHuApplication

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end